classdef controllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ConnectionReferenceTab   matlab.ui.container.Tab
        ConnectionPanel          matlab.ui.container.Panel
        DG535SwitchLabel         matlab.ui.control.Label
        DG535Switch              matlab.ui.control.Switch
        StagesSwitchLabel        matlab.ui.control.Label
        StagesSwitch             matlab.ui.control.Switch
        OscilloscopeSwitchLabel  matlab.ui.control.Label
        OscilloscopeSwitch       matlab.ui.control.Switch
        FreqHzEditFieldLabel     matlab.ui.control.Label
        FreqHzEditField          matlab.ui.control.NumericEditField
        DelayAusEditFieldLabel   matlab.ui.control.Label
        DelayAusEditField        matlab.ui.control.NumericEditField
        DelayBusEditFieldLabel   matlab.ui.control.Label
        DelayBusEditField        matlab.ui.control.NumericEditField
        StageReferencePanel      matlab.ui.container.Panel
        StageXRefGroup           matlab.ui.container.ButtonGroup
        PositiveX                matlab.ui.control.RadioButton
        MidldeX                  matlab.ui.control.RadioButton
        NegativeX                matlab.ui.control.RadioButton
        StageYRefGroup           matlab.ui.container.ButtonGroup
        PositiveY                matlab.ui.control.RadioButton
        MidldeY                  matlab.ui.control.RadioButton
        NegativeY                matlab.ui.control.RadioButton
        StageZRefGroup           matlab.ui.container.ButtonGroup
        PositiveZ                matlab.ui.control.RadioButton
        MidldeZ                  matlab.ui.control.RadioButton
        NegativeZ                matlab.ui.control.RadioButton
        GoRefX                   matlab.ui.control.Button
        GoRefY                   matlab.ui.control.Button
        GoRefZ                   matlab.ui.control.Button
        StageLimitPanel          matlab.ui.container.Panel
        XMaxEditFieldLabel       matlab.ui.control.Label
        XMaxLimit                matlab.ui.control.NumericEditField
        XMinEditFieldLabel       matlab.ui.control.Label
        XMinLimit                matlab.ui.control.NumericEditField
        YMaxEditFieldLabel       matlab.ui.control.Label
        YMaxLimit                matlab.ui.control.NumericEditField
        YMinEditFieldLabel       matlab.ui.control.Label
        YMinLimit                matlab.ui.control.NumericEditField
        ZMaxEditFieldLabel       matlab.ui.control.Label
        ZMaxLimit                matlab.ui.control.NumericEditField
        ZMinEditFieldLabel       matlab.ui.control.Label
        ZMinLimit                matlab.ui.control.NumericEditField
        MovementTab              matlab.ui.container.Tab
        AbsoluteMovementPanel    matlab.ui.container.Panel
        XStageEditFieldLabel     matlab.ui.control.Label
        movXField                matlab.ui.control.NumericEditField
        movXButton               matlab.ui.control.Button
        YStageLabel              matlab.ui.control.Label
        movYField                matlab.ui.control.NumericEditField
        movYButton               matlab.ui.control.Button
        ZStageLabel              matlab.ui.control.Label
        movZField                matlab.ui.control.NumericEditField
        movZButton               matlab.ui.control.Button
        RelativeMovementPanel    matlab.ui.container.Panel
        XStagePanel              matlab.ui.container.Panel
        mvrXField                matlab.ui.control.NumericEditField
        mvrXPlusButton           matlab.ui.control.Button
        mvrXMinButton            matlab.ui.control.Button
        YStagePanel              matlab.ui.container.Panel
        mvrYField                matlab.ui.control.NumericEditField
        mvrYPlusButton           matlab.ui.control.Button
        mvrYMinButton            matlab.ui.control.Button
        ZStagePanel              matlab.ui.container.Panel
        mvrZField                matlab.ui.control.NumericEditField
        mvrZPlusButton           matlab.ui.control.Button
        mvrZMinButton            matlab.ui.control.Button
        StageXPositionPanel      matlab.ui.container.Panel
        XGauge                   matlab.ui.control.LinearGauge
        XPosField                matlab.ui.control.EditField
        StageYPositionPanel      matlab.ui.container.Panel
        YGauge                   matlab.ui.control.LinearGauge
        YPosField                matlab.ui.control.EditField
        StageZPositionPanel      matlab.ui.container.Panel
        ZGauge                   matlab.ui.control.LinearGauge
        ZPosField                matlab.ui.control.EditField
        EmergencyStopButton      matlab.ui.control.Button
        TextDisplay              matlab.ui.control.TextArea
    end


    properties (Access = private)
        Controller, stageY, stageX, stageZ, axisY, axisX, axisZ  % Stage handles
        DSO     % Scope handle
        selectedScan2D % 2D scan selected plane
        matname % filename to be saved
        filenumber % filenumber to be saved
        T2d % Table for 2D raster scan variable holder 
    end

    properties (Access = public)
        dg % DG-535 handle
        startup_point % stage position before connection
    end

    methods (Access = private)
    
        function FemergencyStop(app)
            app.stageX.HLT( app.axisX )
            app.stageY.HLT( app.axisY )
            app.stageZ.HLT( app.axisZ )
        end
        
        function FstagePos(app)
            while 1
                % X-pos
                app.XGauge.Value     = app.stageX.qPOS(app.axisX);
                app.XPosField.Value  = fprintf('Current Position: %5.2f', ...
                    app.stageX.qPOS(app.axisX));
                
                % Y-pos
                app.YGauge.Value     = app.stageY.qPOS(app.axisY);
                app.YPosField.Value  = fprintf('Current Position: %5.2f', ...
                    app.stageY.qPOS(app.axisY));
                
                % X-pos
                app.ZGauge.Value     = app.stageZ.qPOS(app.axisZ);
                app.ZPosField.Value  = fprintf('Current Position: %5.2f', ...
                    app.stageZ.qPOS(app.axisZ));
    
                if (app.stageZ.qONT( app.axisZ ) && app.stageY.qONT( app.axisY ) ...
                        && app.stageX.qONT( app.axisX ))
                    break
                end
            end
        end
        
        function FtextDisplay(app, varargin)
            if nargin == 1
                app.TextDisplay.Value     = varargin;
            else
                app.TextDisplay.Value     = strcat(varargin{1:end});
            end
        end
        
        function FdgInit(app, GPIB_Address)
            app.dg.Comm             = gpib('agilent', 7, GPIB_Address);
            fopen(app.dg.Comm);
            app.dg.ErrorStatus      = dg535CheckError(app.dg);
                if app.dg.ErrorStatus == 0
                    app.TextDisplay.Value     = 'DG-535 connected successfully';
                end
            
            fprintf(app.dg.Comm, 'TM 2; TR 0, %g; DT 2,1,%g; DT 3,1,%g', ...,
                app.FreqHzEditField.Value, app.DelayAusEditField.Value, ...,
                app.DelayBusEditField.Value);            
        end
        
        function FscopeInit(app)
            IP = 'IP:10.147.74.100';
            
            DSOactxHandle     = figure('Visible','off');
            app.DSO           = actxcontrol('LeCroy.ActiveDSOCtrl.1'); % Load ActiveDSO control
            invoke(app.DSO,'MakeConnection', IP); 
            invoke(app.DSO,'WriteString', '*IDN?',true); % Query the scope name and model number
            cellArrayText{1}     = invoke(app.DSO,'ReadString', 1000); % Read back the scope ID to verify connection             
            invoke(app.DSO,'WriteString','VBS? return = app.Acquisition.TriggerMode ',true); % Reads the TriggerMode from the Scope
            cellArrayText{2}     = invoke(app.DSO,'ReadString', 1000); % Read back the TriggerMode       
            
            app.DispConnection.Value     = cellArrayText;
        end
        
        function FstageInit(app)
            if (strfind(evalc('ver'), 'Windows'))
                if (~exist('C:\Users\Public\PI\PI_MATLAB_Driver_GCS2','dir'))
                    error('The PI MATLAB Driver GCS2 was not found on your system. Probably it is not installed. Please run PI_MATLAB_Driver_GCS2_Setup.exe to install the driver.');
                else
                    addpath('C:\Users\Public\PI\PI_MATLAB_Driver_GCS2');
                end
            end
            
            % global Controller;
            
            if(~exist('Controller','var'))
                app.Controller = PI_GCS_Controller();
            end
            
            if(~isa(app.Controller,'PI_GCS_Controller'))
                app.Controller = PI_GCS_Controller();
            end
            
            controllerSN_Y = '0115500076';       
            controllerSN_X = '0085500187';
            controllerSN_Z = '0155500197';
            
            stageType_Z = 'M-403.42S'; % See the label on the stage, e.g. "M-123.456"
            stageType_Y = 'M-405.2S';
            stageType_X = 'M-403.82S';
            
            boolpicontrollerconnected_Y = false;
            boolpicontrollerconnected_X = false;
            boolpicontrollerconnected_Z = false;
            
            
            if (exist('stageY','var'))
                if (app.stageY.IsConnected)
                    boolpicontrollerconnected_Y = true;
                end
            end
        
            if (exist('stageX','var'))
                if (app.stageX.IsConnected)
                    boolpicontrollerconnected_X = true;
                end
            end
        
            if (exist('stageZ','var'))
                if (app.stageZ.IsConnected)
                    boolpicontrollerconnected_Y = true;
                end
            end
    
            if (~boolpicontrollerconnected_Y)
                app.stageY = app.Controller.ConnectUSB(controllerSN_Y);
            end
        
            if (~boolpicontrollerconnected_X)
                app.stageX = app.Controller.ConnectUSB(controllerSN_X);
            end
            
            if (~boolpicontrollerconnected_Z)
                app.stageZ = app.Controller.ConnectUSB(controllerSN_Z);
            end
               
            % query controller identification            
            app.DispConnection.Value     = {app.stageX.qIDN();app.stageY.qIDN();app.stageZ.qIDN()};
            
            % initialize controller
            app.stageY = app.stageY.InitializeController();
            app.stageX = app.stageX.InitializeController();
            app.stageZ = app.stageZ.InitializeController();
            
            % query controller axes
            availableaxes_Y = app.stageY.qSAI_ALLasArray;
            if(isempty(availableaxes_Y))
                error('No axes available');
            end
            
                    %     global axisY;
            app.axisY = availableaxes_Y{1};
                    
            availableaxes_X = app.stageX.qSAI_ALLasArray;
            if(isempty(availableaxes_X))
                error('No axes available');
            end
            
                    %     global axisX;
            app.axisX = availableaxes_X{1};
                    
            availableaxes_Z = app.stageZ.qSAI_ALLasArray;
            if(isempty(availableaxes_Z))
                error('No axes available');
            end
            
                    %     global axisZ;
            app.axisZ = availableaxes_Z{1};
                    
            
             % Change stage                                            
            app.stageY.CST(app.axisY,stageType_Y);                             
            app.stageX.CST(app.axisX,stageType_X);                             
            app.stageZ.CST(app.axisZ,stageType_Z);                              
         
            Sx     = fprintf('the X-stage is %s ', app.stageX.qCST(app.axisY));
            Sy     = fprintf('the Y-stage is %s ', app.stageY.qCST(app.axisX));
            Sz     = fprintf('the Z-stage is %s ', app.stageZ.qCST(app.axisZ));
            app.DispConnection.Value     = {Sx; Sy; Sz};
                    
             % switch on servo and search reference switch to reference stage
            app.stageY.SVO(app.axisY,1);
            app.stageX.SVO(app.axisX,1);
            app.stageZ.SVO(app.axisZ,1);
            
            app.stageX.VEL( app.axisX, 2);
            app.stageY.VEL( app.axisY, 2);
            app.stageZ.VEL( app.axisZ, 2);
            
            % Table for 2D Scan variables container
            stagePair     = [app.stageZ app.stageY; app.stageX app.stageZ; ...
                app.stageX app.stageY];
            AxisPair = [app.axisZ app.axisY; app.axisX app.axisZ; ...
                app.axisX app.axisY];
            stageTypePair = {'z' 'y'; 'x' 'z'; 'x' 'y'};
            isReference   = zeros(3, 1);
            planeNames     = {'Z-Y'; 'X-Z'; 'X-Y'};
            
            app.T2d     = table(stagePair, AxisPair, stageTypePair, ...
                isReference, 'RowNames', planeNames);
            
                % display
            app.startup_point         = [app.stageX.qPOS app.stageY.qPOS app.stageZ.qPOS];
            app.TextDisplay.Value     = fprintf('Startup points are %g\n', app.startup_point);
            pause(.3)
            app.TextDisplay.Value     = 'Do not forget to manually reference the stage before use!';
            
            % wait for Referencing to finish            
%             app.stageX.FRF(app.axisX);
%             app.stageY.FPL(app.axisY);
%             app.stageZ.FNL(app.axisZ);
        end
        
        function FsafeLimiter(app, stageType, whetherReference, varargin)
            % stageType := 'x', 'y', 'z'
            % whetherReference = 1 for referencing.
            % varargin = the rest
            
            isItDangerous = []; 
            
            switch whetherReference
                case 1
                    switch stageType
                        case 'x'
                            switch app.StageXRefGroup.SelectedObject.Text
                                case 'Negative'
                                    if app.XMinLimit.Limits(1) < app.XMinLimit.Value
                                        isItDangerous = 1;
                                    end
                                case 'Middle'
                                    if (app.XMaxLimit.Limits(2)/2 < app.XMinLimit.Value || ...
                                            app.XMaxLimit.Limits(2)/2 > app.XMaxLimit.Value)
                                        isItDangerous = 1;
                                    end
                                otherwise
                                    if app.XMaxLimit.Limits(2) > app.XMaxLimit.Value
                                        isItDangerous = 1;
                                    end
                            end
                            
                        case 'y'
                            switch app.StageYRefGroup.SelectedObject.Text
                                case 'Negative'
                                    if app.YMinLimit.Limits(1) < app.YMinLimit.Value
                                        isItDangerous = 1;
                                    end
                                case 'Middle'
                                    if (app.YMaxLimit.Limits(2)/2 < app.YMinLimit.Value || ...
                                            app.YMaxLimit.Limits(2)/2 > app.YMaxLimit.Value)
                                        isItDangerous = 1;
                                    end
                                otherwise
                                    if app.YMaxLimit.Limits(2) > app.YMaxLimit.Value
                                        isItDangerous = 1;
                                    end
                            end
                            
                        otherwise
                            switch app.StageZRefGroup.SelectedObject.Text
                                case 'Negative'
                                    if app.ZMinLimit.Limits(1) < app.ZMinLimit.Value
                                        isItDangerous = 1;
                                    end
                                case 'Middle'
                                    if (app.ZMaxLimit.Limits(2)/2 < app.ZMinLimit.Value || ...
                                            app.ZMaxLimit.Limits(2)/2 > app.ZMaxLimit.Value)
                                        isItDangerous = 1;
                                    end
                                otherwise
                                    if app.ZMaxLimit.Limits(2) > app.ZMaxLimit.Value
                                        isItDangerous = 1;
                                    end
                            end
                    end
                    
                otherwise
                    switch stageType
                        case 'x'
                            if (varargin{2} < app.XMinLimit.Value || ...
                                    varargin{2} > app.XMaxLimit.Value)
                                isItDangerous = 1;
                            end
                        case 'y'
                            if (varargin{2} < app.YMinLimit.Value || ...
                                    varargin{2} > app.YMaxLimit.Value)
                                isItDangerous = 1;
                            end
                        otherwise
                            if (varargin{2} < app.ZMinLimit.Value || ...
                                    varargin{2} > app.ZMaxLimit.Value)
                                isItDangerous = 1;
                            end
                    end
                    
            end
            
            while 1
                if isItDangerous == 1
                    message = 'the stage will move outside safe limit range';
                    uialert(app.UIFigure, message,'Warning', 'Icon', 'warning');
                    
                    break
                    
                end
            end
        end
        
        function data = Fscan2d(app, pointsPlane1, pointsPlane2, planeCombinationCell, varargin)
            
            fprintf(app.dg.Comm, 'TM 0');
            p             = app.pauseField2D.Value; % pause time between sweeps
            channel       = app.ScopeChannelusedEditField.Value;
            dataScope     = invoke(app.DSO, 'GetScaledWaveformWithTimes', ...
                channel , 1000000, 0);
            
            data    = cell(app.nPoints1Field2D, app.nPoints2Field2D);
            
%             if isempty(varargin)
                hProgress = waitbar(0,'1','Name','Approximate remaining time...',...
                    'CreateCancelBtn', 'setappdata(gcbf,''canceling'',1)');
                
                setappdata(hProgress,'canceling',0)
%             else            end
            
            %% Rastering
            for zc = 1 : app.nPoints2Field2D
                planeCombinationCell{1,1}{1,2}.MOV(planeCombinationCell{1,2}{1,2}, pointsPlane2(zc)); ... 
                    while (~planeCombinationCell{1,1}{1,2}.qONT ( planeCombinationCell{1,2}{1,2} )); end
            
                for yc = 1 : app.nPoints1Field2D
                    planeCombinationCell{1,1}{1,1}.MOV(planeCombinationCell{1,2}{1,1}, pointsPlane1(yc)); ... 
                        while (~planeCombinationCell{1,1}{1,1}.qONT( planeCombinationCell{1,2}{1,2} )); end
                    
                    FstagePos( app )
            
                    % #Maximwashere
                    dataScope='';
                    while isempty(dataScope)
                        invoke(app.DSO, 'WriteString','VBS app.ClearSweeps',true); % ClearSweeps Scope
                        pause(p);
                        dataScope=invoke(app.DSO,'GetScaledWaveformWithTimes',channel ,1000000,0);
                    end
                    % #Maximwashere
            
                    data{yc, zc}  = double(dataScope(2,:));
                    
                    % Progress Bar
                    if isempty(varargin) % for 2D scan
                        totalPoint               = app.nPoints2Field2D.Value * app.nPoints1Field2D.Value;
                        currentNumberOfPoint     = yc * zc;
                    else
                        totalPoint               = app.nPointsXField3D.Value * app.nPointsYField3D.Value ...
                            * app.nPointsZField3D.Value;
                        currentNumberOfPoint     = app.nPointsXField3D.Value * app.nPointsYField3D.Value ...
                            * varargin + yc * zc;                    
                    end                          
                     
                        % Check for Cancel button press
                        if getappdata(hProgress,'canceling')
                            break
                        end
                        
                        % Report current estimate in the waitbar's message field
                        waitbar(currentNumberOfPoint/totalPoint, hProgress, sprintf('%d minutes remaining (Approx.)', ... 
                            (totalPoint-currentNumberOfPoint) * pauseTimeBetweenSweep / 60))    
                    
                end
            end               

                delete(hProgress)       
                
                fprintf(app.dg.Comm, 'TM 2');            
           
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Making sure the button group of scan ready
            app.selectedScan2D = 'Z-Y Plane';
            app.filenumber     = 1;
        end

        % Callback function
        function CurrentPosButton2DPushed(app, event)
            % see the FstageInit(app) function    
            planeCombination  = table2cell(app.T2d({chosenPlane}, :));
            
            app.startPoint1Field2D.Value     = planeCombination{1.1}{1.1}.qPOS();
            app.startPoint2Field2D.Value     = planeCombination{1.1}{1.2}.qPOS();
        end

        % Callback function
        function CurrentPosButton3DPushed(app, event)
            app.startPointXField3D.Value     = app.stageX.qPOS();
            app.startPointYField3D.Value     = app.stageY.qPOS();
            app.startPointZField3D.Value     = app.stageZ.qPOS();
        end

        % Value changed function: DG535Switch
        function DG535SwitchValueChanged(app, event)
            FdgInit(app, 16);            
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            FemergencyStop(app);
        end

        % Callback function
        function GOscan2DButtonPushed(app, event)
            % Chosen Plane
            chosenPlane     = app.PlaneofScan2DButtonGroup.SelectedObject.Text;
                
            % see the FstageInit(app) function    
            planeCombination  = table2cell(app.T2d({chosenPlane}, :));
                        
            % Matrix Position
            startfromPlane_1     = app.startPoint1Field2D.Value;
            nopointsPlane_1      = app.nPoints1Field2D.Value;
            stepsizePlane_1      = app.stepSize1Field2D.Value * 1e-3;
            
            startfromPlane_2     = app.startPoint2Field2D.Value;
            nopointsPlane_2      = app.nPoints2Field2D.Value;
            stepsizePlane_2      = app.stepSize2Field2D.Value * 1e-3;
            
            posMat1      = zeros(1, nopointsPlane_1);            
            for ii = 1:nopointsY
                posMat1(ii)     = startfromPlane_1 + (ii-1)* stepsizePlane_1;
            end
            
            posMat2      = zeros(1, nopointsPlane_2);
            for ii = 1:nopointsZ
                posMat2(ii)     = startfromPlane_2 + (ii-1)* stepsizePlane_2;
            end
            
            % Safe Limiter Check
            FsafeLimiter(app, planeCombination{1,3}{1,1}, 0, 'mov', posMat1(1));
            FsafeLimiter(app, planeCombination{1,3}{1,1}, 0, 'mov', posMat1(end));
            FsafeLimiter(app, planeCombination{1,3}{1,2}, 0, 'mov', posMat2(1));
            FsafeLimiter(app, planeCombination{1,3}{1,2}, 0, 'mov', posMat2(end));
            
            % Variable naming
            prompt = {'What would be the variable name:'};
            dlg_title = 'Variable name';
            num_lines = 1;
            defaultans = {'dataUS or dataNonUS'};
            variableNamebyUser = inputdlg(prompt,dlg_title,num_lines,defaultans);
            variableNamebyUser = cell2mat(variableNamebyUser);
            
            % Accessing variables without putting into memory
            temp = matfile(app.matname, 'Writable', isWritable);
            
            % Putting into action
            temp.dataScan2d.(variableNamebyUser)      = Fscan2d (app, ...
                 posMat1, posMat2, planeCombination, []); % then the scanning app
            
            % Notification window
            message = sprintf('Measurement Done! \n The data is saved within: \n %s', app.matname);
            uialert(app.UIFigure, message, 'Tadaa',...
                'Icon','success');
        end

        % Callback function
        function GOscan3DButtonPushed(app, event)
            
            stageCombinationCell    = cell(1, 2);
            
            stageTypePair   = {'x' 'y'};
            axisPair        = [app.axisX app.axisY];
            
            stageCombinationCell{1, 2}  = stageTypePair;
            stageCombinationCell{1, 2}  = axisPair;
            
            % Matrix Position
            startfromPlane_1     = app.startPointXField3D.Value;
            nopointsPlane_1      = app.nPointsXField3D.Value;
            stepsizePlane_1      = app.stepSizeXField3D.Value * 1e-3;
            
            startfromPlane_2     = app.startPointYField3D.Value;
            nopointsPlane_2      = app.nPointsYField3D.Value;
            stepsizePlane_2      = app.stepSizeYField3D * 1e-3;
            
            startfromPlane_3     = app.startPointZField3D.Value;
            nopointsPlane_3      = app.nPointsZField3D.Value;
            stepsizePlane_3      = app.stepSizeZField3D * 1e-3;
            
            posMat1      = zeros(1, nopointsPlane_1);            
            for ii = 1:nopointsY
                posMat1(ii)     = startfromPlane_1 + (ii-1)* stepsizePlane_1;
            end
            
            posMat2      = zeros(1, nopointsPlane_2);
            for ii = 1:nopointsZ
                posMat2(ii)     = startfromPlane_2 + (ii-1)* stepsizePlane_2;
            end
            
            posMat3      = zeros(1, nopointsPlane_3);
            for ii = 1:nopointsZ
                posMat3(ii)     = startfromPlane_3 + (ii-1)* stepsizePlane_3;
            end
            
            % Safe Limiter Check
            FsafeLimiter(app, 'x', 0, 'mov', posMat1(1));
            FsafeLimiter(app, 'x', 0, 'mov', posMat1(end));
            FsafeLimiter(app, 'y', 0, 'mov', posMat2(1));
            FsafeLimiter(app, 'y', 0, 'mov', posMat2(end));
            FsafeLimiter(app, 'z', 0, 'mov', posMat3(1));
            FsafeLimiter(app, 'z', 0, 'mov', posMat3(end));
            
            % Variable naming
            prompt = {'What would be the variable name:'};
            dlg_title = 'Variable name';
            num_lines = 1;
            defaultans = {'dataUS or dataNonUS'};
            variableNamebyUser = inputdlg(prompt,dlg_title,num_lines,defaultans);
            variableNamebyUser = cell2mat(variableNamebyUser);
            
            % Accessing variables without putting into memory
            temp = matfile(app.matname, 'Writable', isWritable);
            
            % Putting into action            
            for ii = 1 : app.nPointsZField3D
                app.stageZ.MOV(app.axisZ, posMat3(ii)); ... 
                    while (~app.stageZ.qONT ( app.axisZ )); end
                                
                temp.dataScan3d.(variableNamebyUser)(:, :, ii) = Fscan2d(app, pointsPlane1, pointsPlane2, ...
                    stageCombinationCell, ii-1);
            end
            
            % Notification window
            message = sprintf('Measurement Done! \n The data is saved within: \n %s', app.matname);
            uialert(app.UIFigure, message, 'Tadaa',...
                'Icon','success');                       
            
        end

        % Button pushed function: GoRefX
        function GoRefXButtonPushed(app, event)
            stageType = 'x';
            
            switch app.StageXRefGroup.SelectedObject.Text
                case 'Negative'
                    FsafeLimiter(app, stageType, 1);
                    app.stageX.FNL(app.axisX)
                case 'Middle'
                    FsafeLimiter(app, stageType, 1);
                    app.stageX.FRF(app.axisX)
                otherwise
                    FsafeLimiter(app, stageType, 1);
                    app.stageX.FPL(app.axisX)
            end
            
            FstagePos(app);
        end

        % Button pushed function: GoRefY
        function GoRefYButtonPushed(app, event)
            stageType = 'y';
            
            switch app.StageYRefGroup.SelectedObject.Text                
                case 'Negative'
                    FsafeLimiter(app, stageType, 1);
                    app.stageY.FNL(app.axisY)
                case 'Middle'
                    FsafeLimiter(app, stageType, 1);
                    app.stageY.FRF(app.axisY)
                otherwise
                    FsafeLimiter(app, stageType, 1);
                    app.stageY.FPL(app.axisY)
            end
            
            FstagePos(app);
        end

        % Button pushed function: GoRefZ
        function GoRefZButtonPushed(app, event)
            stageType = 'z';
            
            switch app.StageZRefGroup.SelectedObject.Text                
                case 'Negative'
                    FsafeLimiter(app, stageType, 1);
                    app.stageZ.FNL(app.axisZ)
                case 'Middle'
                    FsafeLimiter(app, stageType, 1);
                    app.stageZ.FRF(app.axisZ)
                otherwise
                    FsafeLimiter(app, stageType, 1);
                    app.stageZ.FPL(app.axisZ)
            end
            
            FstagePos(app);
        end

        % Value changed function: OscilloscopeSwitch
        function OscilloscopeSwitchValueChanged(app, event)
            FscopeInit(app);
        end

        % Callback function
        function PlaneofScan2DButtonGroupSelectionChanged(app, event)
            app.selectedScan2D = app.PlaneofScan2DButtonGroup.SelectedObject.Text;
            
            app.TextDisplay.Value = {'For X-Y plane in 2D Scan, plane 1 is X and plane 2 is Y.'; ...
                'Other planes are the same'};
        end

        % Value changed function: StagesSwitch
        function StagesSwitchValueChanged(app, event)
            FstageInit(app);
        end

        % Button pushed function: movXButton
        function movXButtonPushed(app, event)
            target            = app.movXField.Value;
            FsafeLimiter(app, 'x', 0, 'mov', target);
            
            app.stageX.MOV( app.axisX, target )
            FstagePos(app);    
        end

        % Button pushed function: movYButton
        function movYButtonPushed(app, event)
            target     = app.movYField.Value;
            FsafeLimiter(app, 'y', 0, 'mov', target);
            
            app.stageY.MOV( app.axisY, target )
            FstagePos(app);
        end

        % Button pushed function: movZButton
        function movZButtonPushed(app, event)
            target     = app.movZField.Value;
            FsafeLimiter(app, 'z', 0, 'mov', target);
            
            app.stageZ.MOV( app.axisZ, target )
            FstagePos(app);
        end

        % Button pushed function: mvrXMinButton
        function mvrXMinButtonPushed(app, event)
            target            = app.stageX.qPOS() - app.mvrXField.Value;
            FsafeLimiter(app, 'x', 0, 'mvr', target);
            
            app.stageX.MVR( app.axisX, -(app.mvrXField.Value) )
            FstagePos(app);
        end

        % Button pushed function: mvrXPlusButton
        function mvrXPlusButtonPushed(app, event)
            target            = app.stageX.qPOS() + app.mvrXField.Value;
            FsafeLimiter(app, 'x', 0, 'mvr', target);
            
            app.stageX.MVR( app.axisX, app.mvrXField.Value )
            FstagePos(app);
        end

        % Button pushed function: mvrYMinButton
        function mvrYMinButtonPushed(app, event)
            target            = app.stageY.qPOS() - app.mvrYField.Value;
            FsafeLimiter(app, 'y', 0, 'mvr', target);
            
            app.stageY.MVR( app.axisY, -(app.mvrYField.Value) )
            FstagePos(app);
        end

        % Button pushed function: mvrYPlusButton
        function mvrYPlusButtonPushed(app, event)
            target            = app.stageY.qPOS() + app.mvrYField.Value;
            FsafeLimiter(app, 'y', 0, 'mvr', target);
            
            app.stageY.MVR( app.axisY, app.mvrYField.Value )
            FstagePos(app);
        end

        % Button pushed function: mvrZMinButton
        function mvrZMinButtonPushed(app, event)
            target            = app.stageZ.qPOS() - app.mvrZField.Value;
            FsafeLimiter(app, 'z', 0, 'mvr', target);
            
            app.stageZ.MVR( app.axisZ, -(app.mvrZField.Value) )
            FstagePos(app);
        end

        % Button pushed function: mvrZPlusButton
        function mvrZPlusButtonPushed(app, event)
            target            = app.stageZ.qPOS() + app.mvrZField.Value;
            FsafeLimiter(app, 'z', 0, 'mvr', target);
            
            app.stageZ.MVR( app.axisZ, app.mvrZField.Value )
            FstagePos(app);
        end

        % Callback function
        function varInitScan2DButtonPushed(app, event)
            % Variable initiation
            field1 = 'dataUS';  value1 = cell(app.nPoints1Field2D, app.nPoints2Field2D);
            field2 = 'dataNonUS';  value2 = value1;
            
            dataScan2d = struct(field1, value1, field2, value2);
            
            % Saving for Memory dump
            formatOut     = 'yyyy-mm-dd';
            app.matname       = sprintf('%s-%d.mat', datestr(now, formatOut), app.filenumber);
            
            while exist(app.matname)~=0
                app.filenumber     = app.filenumber + 1;
                app.matname=sprintf('%s-%d.mat', datestr(now,formatOut), app.filenumber);
            end
            
            save(app.matname, 'dataScan2d', '-v7.3');
            
        end

        % Callback function
        function varInitScan3DButtonPushed(app, event)
             % Variable initiation
            field1 = 'dataUS';  value1 = cell(app.nPointsXField3D, app.nPointsYField3D, app.nPointsZField3D);
            field2 = 'dataNonUS';  value2 = value1;
            
            dataScan3d = struct(field1, value1, field2, value2);
            
            % Saving for Memory dump
            formatOut     = 'yyyy-mm-dd';
            app.matname       = sprintf('%s-%d.mat', datestr(now, formatOut), app.filenumber);
            
            while exist(app.matname)~=0
                app.filenumber     = app.filenumber + 1;
                app.matname=sprintf('%s-%d.mat', datestr(now,formatOut), app.filenumber);
            end
            
            save(app.matname, 'dataScan3d', '-v7.3');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionReferenceTab = uitab(app.TabGroup);
            app.ConnectionReferenceTab.Title = 'Connection & Reference';
            app.ConnectionPanel = uipanel(app.ConnectionReferenceTab);
            app.ConnectionPanel.Title = 'Connection';
            app.DG535SwitchLabel = uilabel(app.ConnectionPanel);
            app.DG535SwitchLabel.Text = 'DG535';
            app.DG535Switch = uiswitch(app.ConnectionPanel, 'slider');
            app.DG535Switch.Items = {'Off', 'Connect'};
            app.DG535Switch.ValueChangedFcn = createCallbackFcn(app, @DG535SwitchValueChanged, true);
            app.StagesSwitchLabel = uilabel(app.ConnectionPanel);
            app.StagesSwitchLabel.Text = 'Stages';
            app.StagesSwitch = uiswitch(app.ConnectionPanel, 'slider');
            app.StagesSwitch.Items = {'Off', 'Connect'};
            app.StagesSwitch.ValueChangedFcn = createCallbackFcn(app, @StagesSwitchValueChanged, true);
            app.OscilloscopeSwitchLabel = uilabel(app.ConnectionPanel);
            app.OscilloscopeSwitchLabel.Text = 'Oscilloscope';
            app.OscilloscopeSwitch = uiswitch(app.ConnectionPanel, 'slider');
            app.OscilloscopeSwitch.Items = {'Off', 'Connect'};
            app.OscilloscopeSwitch.ValueChangedFcn = createCallbackFcn(app, @OscilloscopeSwitchValueChanged, true);
            app.FreqHzEditFieldLabel = uilabel(app.ConnectionPanel);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.FreqHzEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.DelayAusEditFieldLabel = uilabel(app.ConnectionPanel);
            app.DelayAusEditFieldLabel.Text = 'Delay-A (us)';
            app.DelayAusEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.DelayBusEditFieldLabel = uilabel(app.ConnectionPanel);
            app.DelayBusEditFieldLabel.Text = 'Delay-B (us)';
            app.DelayBusEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.StageReferencePanel = uipanel(app.ConnectionReferenceTab);
            app.StageReferencePanel.Title = 'Stage Reference';
            app.StageXRefGroup = uibuttongroup(app.StageReferencePanel);
            app.StageXRefGroup.Title = 'Stage X';
            app.PositiveX = uiradiobutton(app.StageXRefGroup);
            app.PositiveX.Text = 'Positive';
            app.PositiveX.Value = true;
            app.MidldeX = uiradiobutton(app.StageXRefGroup);
            app.MidldeX.Text = 'Midlde';
            app.NegativeX = uiradiobutton(app.StageXRefGroup);
            app.NegativeX.Text = 'Negative';
            app.StageYRefGroup = uibuttongroup(app.StageReferencePanel);
            app.StageYRefGroup.Title = 'Stage Y';
            app.PositiveY = uiradiobutton(app.StageYRefGroup);
            app.PositiveY.Text = 'Positive';
            app.PositiveY.Value = true;
            app.MidldeY = uiradiobutton(app.StageYRefGroup);
            app.MidldeY.Text = 'Midlde';
            app.NegativeY = uiradiobutton(app.StageYRefGroup);
            app.NegativeY.Text = 'Negative';
            app.StageZRefGroup = uibuttongroup(app.StageReferencePanel);
            app.StageZRefGroup.Title = 'Stage Z';
            app.PositiveZ = uiradiobutton(app.StageZRefGroup);
            app.PositiveZ.Text = 'Positive';
            app.PositiveZ.Value = true;
            app.MidldeZ = uiradiobutton(app.StageZRefGroup);
            app.MidldeZ.Text = 'Midlde';
            app.NegativeZ = uiradiobutton(app.StageZRefGroup);
            app.NegativeZ.Text = 'Negative';
            app.GoRefX = uibutton(app.StageReferencePanel, 'push');
            app.GoRefX.ButtonPushedFcn = createCallbackFcn(app, @GoRefXButtonPushed, true);
            app.GoRefX.Text = 'Go';
            app.GoRefY = uibutton(app.StageReferencePanel, 'push');
            app.GoRefY.ButtonPushedFcn = createCallbackFcn(app, @GoRefYButtonPushed, true);
            app.GoRefY.Text = 'Go';
            app.GoRefZ = uibutton(app.StageReferencePanel, 'push');
            app.GoRefZ.ButtonPushedFcn = createCallbackFcn(app, @GoRefZButtonPushed, true);
            app.GoRefZ.Text = 'Go';
            app.StageLimitPanel = uipanel(app.ConnectionReferenceTab);
            app.StageLimitPanel.Title = 'Stage Limit';
            app.XMaxEditFieldLabel = uilabel(app.StageLimitPanel);
            app.XMaxEditFieldLabel.Text = 'X-Max';
            app.XMaxLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.XMaxLimit.Limits = [0 200];
            app.XMaxLimit.Value = 200;
            app.XMinEditFieldLabel = uilabel(app.StageLimitPanel);
            app.XMinEditFieldLabel.Text = 'X-Min';
            app.XMinLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.XMinLimit.Limits = [0 200];
            app.YMaxEditFieldLabel = uilabel(app.StageLimitPanel);
            app.YMaxEditFieldLabel.Text = 'Y-Max';
            app.YMaxLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.YMaxLimit.Limits = [0 50];
            app.YMaxLimit.Value = 50;
            app.YMinEditFieldLabel = uilabel(app.StageLimitPanel);
            app.YMinEditFieldLabel.Text = 'Y-Min';
            app.YMinLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.YMinLimit.Limits = [0 50];
            app.ZMaxEditFieldLabel = uilabel(app.StageLimitPanel);
            app.ZMaxEditFieldLabel.Text = 'Z-Max';
            app.ZMaxLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.ZMaxLimit.Limits = [0 100];
            app.ZMaxLimit.Value = 100;
            app.ZMinEditFieldLabel = uilabel(app.StageLimitPanel);
            app.ZMinEditFieldLabel.Text = 'Z-Min';
            app.ZMinLimit = uieditfield(app.StageLimitPanel, 'numeric');
            app.ZMinLimit.Limits = [0 100];
            app.MovementTab = uitab(app.TabGroup);
            app.MovementTab.Title = 'Movement';
            app.AbsoluteMovementPanel = uipanel(app.MovementTab);
            app.AbsoluteMovementPanel.Title = 'Absolute Movement';
            app.XStageEditFieldLabel = uilabel(app.AbsoluteMovementPanel);
            app.XStageEditFieldLabel.Text = 'X-Stage';
            app.movXField = uieditfield(app.AbsoluteMovementPanel, 'numeric');
            app.movXButton = uibutton(app.AbsoluteMovementPanel, 'push');
            app.movXButton.ButtonPushedFcn = createCallbackFcn(app, @movXButtonPushed, true);
            app.movXButton.Text = 'Go';
            app.YStageLabel = uilabel(app.AbsoluteMovementPanel);
            app.YStageLabel.Text = 'Y-Stage';
            app.movYField = uieditfield(app.AbsoluteMovementPanel, 'numeric');
            app.movYButton = uibutton(app.AbsoluteMovementPanel, 'push');
            app.movYButton.ButtonPushedFcn = createCallbackFcn(app, @movYButtonPushed, true);
            app.movYButton.Text = 'Go';
            app.ZStageLabel = uilabel(app.AbsoluteMovementPanel);
            app.ZStageLabel.Text = 'Z-Stage';
            app.movZField = uieditfield(app.AbsoluteMovementPanel, 'numeric');
            app.movZButton = uibutton(app.AbsoluteMovementPanel, 'push');
            app.movZButton.ButtonPushedFcn = createCallbackFcn(app, @movZButtonPushed, true);
            app.movZButton.Text = 'Go';
            app.RelativeMovementPanel = uipanel(app.MovementTab);
            app.RelativeMovementPanel.Title = 'Relative Movement';
            app.XStagePanel = uipanel(app.RelativeMovementPanel);
            app.XStagePanel.Title = 'X-Stage';
            app.mvrXField = uieditfield(app.XStagePanel, 'numeric');
            app.mvrXPlusButton = uibutton(app.XStagePanel, 'push');
            app.mvrXPlusButton.ButtonPushedFcn = createCallbackFcn(app, @mvrXPlusButtonPushed, true);
            app.mvrXPlusButton.Text = '+';
            app.mvrXMinButton = uibutton(app.XStagePanel, 'push');
            app.mvrXMinButton.ButtonPushedFcn = createCallbackFcn(app, @mvrXMinButtonPushed, true);
            app.mvrXMinButton.Text = '-';
            app.YStagePanel = uipanel(app.RelativeMovementPanel);
            app.YStagePanel.Title = 'Y-Stage';
            app.mvrYField = uieditfield(app.YStagePanel, 'numeric');
            app.mvrYPlusButton = uibutton(app.YStagePanel, 'push');
            app.mvrYPlusButton.ButtonPushedFcn = createCallbackFcn(app, @mvrYPlusButtonPushed, true);
            app.mvrYPlusButton.Text = '+';
            app.mvrYMinButton = uibutton(app.YStagePanel, 'push');
            app.mvrYMinButton.ButtonPushedFcn = createCallbackFcn(app, @mvrYMinButtonPushed, true);
            app.mvrYMinButton.Text = '-';
            app.ZStagePanel = uipanel(app.RelativeMovementPanel);
            app.ZStagePanel.Title = 'Z-Stage';
            app.mvrZField = uieditfield(app.ZStagePanel, 'numeric');
            app.mvrZPlusButton = uibutton(app.ZStagePanel, 'push');
            app.mvrZPlusButton.ButtonPushedFcn = createCallbackFcn(app, @mvrZPlusButtonPushed, true);
            app.mvrZPlusButton.Text = '+';
            app.mvrZMinButton = uibutton(app.ZStagePanel, 'push');
            app.mvrZMinButton.ButtonPushedFcn = createCallbackFcn(app, @mvrZMinButtonPushed, true);
            app.mvrZMinButton.Text = '-';
            app.StageXPositionPanel = uipanel(app.UIFigure);
            app.StageXPositionPanel.Title = 'Stage X Position';
            app.XGauge = uigauge(app.StageXPositionPanel, 'linear');
            app.XGauge.Limits = [0 200];
            app.XGauge.MajorTicks = [0 40 80 120 160 200];
            app.XPosField = uieditfield(app.StageXPositionPanel, 'text');
            app.StageYPositionPanel = uipanel(app.UIFigure);
            app.StageYPositionPanel.Title = 'Stage Y Position';
            app.YGauge = uigauge(app.StageYPositionPanel, 'linear');
            app.YGauge.Limits = [0 50];
            app.YGauge.MajorTicks = [0 10 20 30 40 50];
            app.YPosField = uieditfield(app.StageYPositionPanel, 'text');
            app.StageZPositionPanel = uipanel(app.UIFigure);
            app.StageZPositionPanel.Title = 'Stage Z Position';
            app.ZGauge = uigauge(app.StageZPositionPanel, 'linear');
            app.ZGauge.MajorTicks = [0 25 50 75 100];
            app.ZPosField = uieditfield(app.StageZPositionPanel, 'text');
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'EMERGENCY STOP';
            app.TextDisplay = uitextarea(app.UIFigure);
            app.TextDisplay.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = controllerApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end